classdef GenerateTextFiles < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        OverrideAFFTFilenameCheckBox    matlab.ui.control.CheckBox
        AFFTFileGeneratedLabel          matlab.ui.control.Label
        HzLabel                         matlab.ui.control.Label
        Label_5                         matlab.ui.control.Label
        PointsLabel                     matlab.ui.control.Label
        RPMLabel_3                      matlab.ui.control.Label
        RPMLabel_2                      matlab.ui.control.Label
        RPMLabel                        matlab.ui.control.Label
        IncludeinmemorymatrixLabel      matlab.ui.control.Label
        WindTurbineFaultDetectionFileGenerationToolLabel  matlab.ui.control.Label
        DataFolderButton                matlab.ui.control.Button
        Label_2                         matlab.ui.control.Label
        NSETPropertiesLabel             matlab.ui.control.Label
        WindSpeedCheckBox               matlab.ui.control.CheckBox
        RotorSpeedCheckBox              matlab.ui.control.CheckBox
        RMSAccelerationCheckBox         matlab.ui.control.CheckBox
        LineLengthAccelerationCheckBox  matlab.ui.control.CheckBox
        Label_3                         matlab.ui.control.Label
        DataDir                         matlab.ui.control.TextArea
        PowerOutputCheckBox             matlab.ui.control.CheckBox
        AFFTPropertiesLabel             matlab.ui.control.Label
        RotorSpeedBinsLabel             matlab.ui.control.Label
        GenerateNSETFilesButton         matlab.ui.control.Button
        OutputFolderButton              matlab.ui.control.Button
        OutputDir                       matlab.ui.control.TextArea
        GenerateAFFTFileButton          matlab.ui.control.Button
        AFFTFileNameEditField           matlab.ui.control.EditField
        FileNameEditFieldLabel          matlab.ui.control.Label
        RMinimumEditField               matlab.ui.control.NumericEditField
        MinimumEditFieldLabel           matlab.ui.control.Label
        RMaximumEditField               matlab.ui.control.NumericEditField
        MaximumEditFieldLabel           matlab.ui.control.Label
        RStepEditField                  matlab.ui.control.NumericEditField
        StepEditFieldLabel              matlab.ui.control.Label
        FFTLengthEditField              matlab.ui.control.NumericEditField
        FFTLengthEditFieldLabel         matlab.ui.control.Label
        FreqBinWidthEditFieldLabel      matlab.ui.control.Label
        FreqBinWidthEditField           matlab.ui.control.NumericEditField
        ThresholdMultiplierEditFieldLabel  matlab.ui.control.Label
        AFFTThresholdMultiplierEditField  matlab.ui.control.NumericEditField
        OverrideNSETFilenameCheckBox    matlab.ui.control.CheckBox
        MemoryMatrixLabel               matlab.ui.control.Label
        NSETMemoryEditField             matlab.ui.control.EditField
        DbunDLabel                      matlab.ui.control.Label
        NSETInverseEditField            matlab.ui.control.EditField
        RCondThresholdEditFieldLabel    matlab.ui.control.Label
        RCondThresholdEditField         matlab.ui.control.NumericEditField
        Label_6                         matlab.ui.control.Label
        Image2                          matlab.ui.control.Image
        Label                           matlab.ui.control.Label
        NSETFilesGeneratedLabel         matlab.ui.control.Label
        DebugCheckBox                   matlab.ui.control.CheckBox
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.DataDir.Value = strcat(pwd,'\Data');
            app.OutputDir.Value = strcat(pwd,'\OutputFiles');
            
            path(path,strcat(pwd,'\Algorithms'));
            path(path,strcat(pwd,'\Algorithms\NSET'));
            path(path,strcat(pwd,'\Algorithms\AFFT'));
            path(path,strcat(pwd,'\Images'))
            
            global debug
            debug = false;

        end

        % Button pushed function: DataFolderButton
        function updateDirectory(app, event)
            seldir = uigetdir;
            figure(app.UIFigure);
            changeDirBox(app,seldir)
        end

        % Value changed function: DataDir
        function changeDirBox(app, event)
            app.DataDir.Value = event;
            
        end

        % Value changed function: OutputDir
        function changeOutBox(app, event)
            app.OutputDir.Value = event;
            
        end

        % Button pushed function: OutputFolderButton
        function updateDirectoryOut(app, event)
            seldir = uigetdir;
            figure(app.UIFigure);
            changeOutBox(app,seldir)
        end

        % Button pushed function: GenerateAFFTFileButton
        function GenerateAFFTThreshold(app, event)
            app.AFFTFileGeneratedLabel.Visible = false;
            pause(0.1)
            
            K_thr    = app.AFFTThresholdMultiplierEditField.Value;
            R_low    = app.RMinimumEditField.Value;
            R_high   = app.RMaximumEditField.Value;
            R_step   = app.RStepEditField.Value;
            R_bins   = R_low:R_step:R_high;
            FFT_len  = app.FFTLengthEditField.Value;
            bin_wid  = app.FreqBinWidthEditField.Value;
            fileName = app.AFFTFileNameEditField.Value;
            
            dataDirectory = string(app.DataDir.Value);
            outputDirectory = string(app.OutputDir.Value);
            path(path,dataDirectory)
            [~,freqs,threshold] = AFFT_thresholds(K_thr,R_bins,FFT_len,bin_wid,fileName,dataDirectory,outputDirectory);
            app.AFFTFileGeneratedLabel.Visible = true;
            
            [ROTS,FREQS] = meshgrid(R_bins,freqs);
            surface = surf(app.UIAxes2,FREQS,ROTS,threshold./16384);
            view(app.UIAxes2,[-185,45])
            surface.EdgeColor = 'none';
            surface.FaceColor = 'interp';
            
            
        end

        % Value changed function: OverrideAFFTFilenameCheckBox
        function AllowAFFTFileNameChange(app, event)
            value = app.OverrideAFFTFilenameCheckBox.Value;
            if value
                app.AFFTFileNameEditField.Editable = true;
            else
                app.AFFTFileNameEditField.Editable = false;
            end
        end

        % Value changed function: OverrideNSETFilenameCheckBox
        function AllowNSETFileNameChange(app, event)
            value = app.OverrideNSETFilenameCheckBox.Value;
            if value
                app.NSETMemoryEditField.Editable = true;
                app.NSETInverseEditField.Editable = true;
            else
                app.NSETMemoryEditField.Editable = false;
                app.NSETInverseEditField.Editable = false;
            end
        end

        % Button pushed function: GenerateNSETFilesButton
        function GenerateNSETFiles(app, event)
            app.NSETFilesGeneratedLabel.Visible = false;
            pause(0.1)
            
            memName = string(app.NSETMemoryEditField.Value);
            invName = string(app.NSETInverseEditField.Value);
            fileNames = [memName,invName];
            disp(fileNames)
            
            dataDirectory = string(app.DataDir.Value);
            outputDirectory = string(app.OutputDir.Value);
            path(path,dataDirectory)
            
            saveRows = [];
            if app.RotorSpeedCheckBox.Value
                saveRows=[saveRows,1];
            end
            if app.WindSpeedCheckBox.Value
                saveRows=[saveRows,2];
            end
            if app.PowerOutputCheckBox.Value
                saveRows=[saveRows,3];
            end
            if app.RMSAccelerationCheckBox.Value
                saveRows=[saveRows,[4,5,6]];
            end
            if app.LineLengthAccelerationCheckBox.Value
                saveRows=[saveRows,[7,8,9]];
            end
            rcondThresh = app.RCondThresholdEditField.Value;
            [~,mem_long] = NSET_matrices(rcondThresh,saveRows,fileNames,dataDirectory,outputDirectory);
            app.NSETFilesGeneratedLabel.Visible = true;
            
            plot(app.UIAxes,mem_long(1,:).*30,mem_long(2,:).*300,'o')
            
            
        end

        % Callback function
        function Reset(app, event)
            app.AFFTFileGeneratedLabel.Visible = false;
            app.NSETFilesGeneratedLabel.Visible = false;
        end

        % Value changed function: DebugCheckBox
        function DebugAFFT(app, event)
            global debug
            value = app.DebugCheckBox.Value;
            
            if value
                debug = true;
            else
                debug = false;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.OverrideAFFTFilenameCheckBox = uicheckbox(app.GridLayout);
            app.OverrideAFFTFilenameCheckBox.ValueChangedFcn = createCallbackFcn(app, @AllowAFFTFileNameChange, true);
            app.OverrideAFFTFilenameCheckBox.Tooltip = {'WARNING: The filename to be loaded is hardcoded in the Turbine Control software. Make sure you know what you''re doing!'};
            app.OverrideAFFTFilenameCheckBox.Text = 'Override file name';
            app.AFFTFileGeneratedLabel = uilabel(app.GridLayout);
            app.AFFTFileGeneratedLabel.Visible = 'off';
            app.AFFTFileGeneratedLabel.Text = 'AFFT File Generated!';
            app.HzLabel = uilabel(app.GridLayout);
            app.HzLabel.Text = 'Hz';
            app.Label_5 = uilabel(app.GridLayout);
            app.Label_5.Text = '#';
            app.PointsLabel = uilabel(app.GridLayout);
            app.PointsLabel.Text = 'Points';
            app.RPMLabel_3 = uilabel(app.GridLayout);
            app.RPMLabel_3.Text = 'RPM';
            app.RPMLabel_2 = uilabel(app.GridLayout);
            app.RPMLabel_2.Text = 'RPM';
            app.RPMLabel = uilabel(app.GridLayout);
            app.RPMLabel.Text = 'RPM';
            app.IncludeinmemorymatrixLabel = uilabel(app.GridLayout);
            app.IncludeinmemorymatrixLabel.Text = 'Include in memory matrix:';
            app.WindTurbineFaultDetectionFileGenerationToolLabel = uilabel(app.GridLayout);
            app.WindTurbineFaultDetectionFileGenerationToolLabel.Text = 'Wind Turbine: Fault Detection File Generation Tool';
            app.DataFolderButton = uibutton(app.GridLayout, 'push');
            app.DataFolderButton.ButtonPushedFcn = createCallbackFcn(app, @updateDirectory, true);
            app.DataFolderButton.Tooltip = {'.mat files in the Data folder must ONLY include those you want to use to generate files'};
            app.DataFolderButton.Text = 'Data Folder';
            app.Label_2 = uilabel(app.GridLayout);
            app.Label_2.Text = '___________________________________________________________________________________';
            app.NSETPropertiesLabel = uilabel(app.GridLayout);
            app.NSETPropertiesLabel.Text = 'NSET Properties';
            app.WindSpeedCheckBox = uicheckbox(app.GridLayout);
            app.WindSpeedCheckBox.Text = 'Wind Speed';
            app.RotorSpeedCheckBox = uicheckbox(app.GridLayout);
            app.RotorSpeedCheckBox.Text = 'Rotor Speed';
            app.RotorSpeedCheckBox.Value = true;
            app.RMSAccelerationCheckBox = uicheckbox(app.GridLayout);
            app.RMSAccelerationCheckBox.Text = 'RMS Acceleration';
            app.RMSAccelerationCheckBox.Value = true;
            app.LineLengthAccelerationCheckBox = uicheckbox(app.GridLayout);
            app.LineLengthAccelerationCheckBox.Text = 'Line Length Acceleration';
            app.LineLengthAccelerationCheckBox.Value = true;
            app.Label_3 = uilabel(app.GridLayout);
            app.Label_3.Text = '___________________________________________________________________________________';
            app.DataDir = uitextarea(app.GridLayout);
            app.DataDir.ValueChangedFcn = createCallbackFcn(app, @changeDirBox, true);
            app.PowerOutputCheckBox = uicheckbox(app.GridLayout);
            app.PowerOutputCheckBox.Text = 'Power Output';
            app.AFFTPropertiesLabel = uilabel(app.GridLayout);
            app.AFFTPropertiesLabel.Text = 'AFFT Properties';
            app.RotorSpeedBinsLabel = uilabel(app.GridLayout);
            app.RotorSpeedBinsLabel.Text = 'Rotor Speed Bins';
            app.GenerateNSETFilesButton = uibutton(app.GridLayout, 'push');
            app.GenerateNSETFilesButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateNSETFiles, true);
            app.GenerateNSETFilesButton.Text = 'Generate NSET Files';
            app.OutputFolderButton = uibutton(app.GridLayout, 'push');
            app.OutputFolderButton.ButtonPushedFcn = createCallbackFcn(app, @updateDirectoryOut, true);
            app.OutputFolderButton.Text = 'Output Folder';
            app.OutputDir = uitextarea(app.GridLayout);
            app.OutputDir.ValueChangedFcn = createCallbackFcn(app, @changeOutBox, true);
            app.GenerateAFFTFileButton = uibutton(app.GridLayout, 'push');
            app.GenerateAFFTFileButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateAFFTThreshold, true);
            app.GenerateAFFTFileButton.Text = 'Generate AFFT File';
            app.AFFTFileNameEditField = uieditfield(app.GridLayout, 'text');
            app.AFFTFileNameEditField.Editable = 'off';
            app.AFFTFileNameEditField.Tooltip = {'WARNING: The filename to be loaded is hardcoded in the Turbine Control software. Make sure you know what you''re doing!'; ''; 'This must end in the file extension (.txt)'};
            app.AFFTFileNameEditField.Value = 'AFFT_threshold.txt';
            app.FileNameEditFieldLabel = uilabel(app.GridLayout);
            app.FileNameEditFieldLabel.Text = 'File Name';
            app.RMinimumEditField = uieditfield(app.GridLayout, 'numeric');
            app.RMinimumEditField.Limits = [10 100];
            app.RMinimumEditField.Tooltip = {'Minimum rotorspeed to generate an FFT threshold (speeds below min - step/2 will be grouped into this bin)'};
            app.RMinimumEditField.Value = 32.5;
            app.MinimumEditFieldLabel = uilabel(app.GridLayout);
            app.MinimumEditFieldLabel.Text = 'Minimum';
            app.RMaximumEditField = uieditfield(app.GridLayout, 'numeric');
            app.RMaximumEditField.Limits = [30 300];
            app.RMaximumEditField.Tooltip = {'Minimum rotorspeed to generate an FFT threshold (speeds above max + step/2 will be grouped into this bin)'};
            app.RMaximumEditField.Value = 207.5;
            app.MaximumEditFieldLabel = uilabel(app.GridLayout);
            app.MaximumEditFieldLabel.Text = 'Maximum';
            app.RStepEditField = uieditfield(app.GridLayout, 'numeric');
            app.RStepEditField.Limits = [1 50];
            app.RStepEditField.Tooltip = {'Step size per threshold'};
            app.RStepEditField.Value = 5;
            app.StepEditFieldLabel = uilabel(app.GridLayout);
            app.StepEditFieldLabel.Text = 'Step';
            app.FFTLengthEditField = uieditfield(app.GridLayout, 'numeric');
            app.FFTLengthEditField.Limits = [2 Inf];
            app.FFTLengthEditField.Tooltip = {'Number of datapoints to include in each FFT. Ideally, should be a power of 2 - not strictly necessary, but increases speed.'; ''; 'This should be identical to the FFT length taken by the Turbine Control software, to minimize variations between threshold and real-time FFTs'};
            app.FFTLengthEditField.Value = 1024;
            app.FFTLengthEditFieldLabel = uilabel(app.GridLayout);
            app.FFTLengthEditFieldLabel.Text = 'FFT Length';
            app.FreqBinWidthEditFieldLabel = uilabel(app.GridLayout);
            app.FreqBinWidthEditFieldLabel.Text = 'Freq Bin Width';
            app.FreqBinWidthEditField = uieditfield(app.GridLayout, 'numeric');
            app.FreqBinWidthEditField.Limits = [0.2 5];
            app.FreqBinWidthEditField.Tooltip = {'Width around frequency peaks to generate thresholds'};
            app.FreqBinWidthEditField.Value = 1;
            app.ThresholdMultiplierEditFieldLabel = uilabel(app.GridLayout);
            app.ThresholdMultiplierEditFieldLabel.Text = 'Threshold Multiplier';
            app.AFFTThresholdMultiplierEditField = uieditfield(app.GridLayout, 'numeric');
            app.AFFTThresholdMultiplierEditField.Limits = [0 5];
            app.AFFTThresholdMultiplierEditField.Tooltip = {'Threshold multiplier to determine faults. Minimum: 1'; 'Maximum: 5'; 'Good value: 2-2.5'};
            app.AFFTThresholdMultiplierEditField.Value = 2.5;
            app.OverrideNSETFilenameCheckBox = uicheckbox(app.GridLayout);
            app.OverrideNSETFilenameCheckBox.ValueChangedFcn = createCallbackFcn(app, @AllowNSETFileNameChange, true);
            app.OverrideNSETFilenameCheckBox.Tooltip = {'WARNING: The filename to be loaded is hardcoded in the Turbine Control software. Make sure you know what you''re doing!'};
            app.OverrideNSETFilenameCheckBox.Text = 'Override file name';
            app.MemoryMatrixLabel = uilabel(app.GridLayout);
            app.MemoryMatrixLabel.Text = 'Memory Matrix';
            app.NSETMemoryEditField = uieditfield(app.GridLayout, 'text');
            app.NSETMemoryEditField.Editable = 'off';
            app.NSETMemoryEditField.Tooltip = {'WARNING: The filename to be loaded is hardcoded in the Turbine Control software. Make sure you know what you''re doing!'; ''; 'This must end in the file extension (.txt)'};
            app.NSETMemoryEditField.Value = 'NSET_memory.txt';
            app.DbunDLabel = uilabel(app.GridLayout);
            app.DbunDLabel.Text = 'D'' (bun) D';
            app.NSETInverseEditField = uieditfield(app.GridLayout, 'text');
            app.NSETInverseEditField.Editable = 'off';
            app.NSETInverseEditField.Tooltip = {'WARNING: The filename to be loaded is hardcoded in the Turbine Control software. Make sure you know what you''re doing!'; ''; 'This must end in the file extension (.txt)'};
            app.NSETInverseEditField.Value = 'NSET_inverse.txt';
            app.RCondThresholdEditFieldLabel = uilabel(app.GridLayout);
            app.RCondThresholdEditFieldLabel.Text = 'RCond Threshold';
            app.RCondThresholdEditField = uieditfield(app.GridLayout, 'numeric');
            app.RCondThresholdEditField.Limits = [1e-20 1];
            app.RCondThresholdEditField.Tooltip = {'Minimum allowable reciprocal condition number for memory matrix formation. This describes the accuracy of matrix inversion; a "sweet spot" must be found between 1 and 1E-20. At both ends of the scale, NSET has no prediction power. Experience shows that values between 1E-8 and 1E-12 are decent. The larger the number, the smaller the resulting memory matrix.'};
            app.RCondThresholdEditField.Value = 1e-08;
            app.Label_6 = uilabel(app.GridLayout);
            app.Label_6.Text = '#';
            app.Image2 = uiimage(app.GridLayout);
            app.Image2.ImageSource = 'calpolymechanicalengineering.png';
            app.Label = uilabel(app.GridLayout);
            app.Label.Text = {'This app generates text files necessary for running fault detection algorithms. It generates three files:'; '    NSET_memory.txt      -  NSET memory matrix'; '    NSET_inverse.txt       -  The quantity [ D'' (bun) D ], where D is the NSET memory matrix'; '    AFFT_threshold.txt    -  AFFT adapative threshold for FFTs'; ''; 'For more info on the NSET and AFFT algorithms, please see Luke Costello''s MS thesis.'; ''; ''; 'NOTE: Files in the Data folder must be preprocessed into .mat files using the autoprocess script!'};
            app.NSETFilesGeneratedLabel = uilabel(app.GridLayout);
            app.NSETFilesGeneratedLabel.Visible = 'off';
            app.NSETFilesGeneratedLabel.Text = 'NSET Files Generated!';
            app.DebugCheckBox = uicheckbox(app.GridLayout);
            app.DebugCheckBox.ValueChangedFcn = createCallbackFcn(app, @DebugAFFT, true);
            app.DebugCheckBox.Text = 'Debug';
            app.UIAxes2 = uiaxes(app.GridLayout);
            title(app.UIAxes2, 'AFFT 3D Threshold')
            xlabel(app.UIAxes2, 'Frequency [Hz]')
            ylabel(app.UIAxes2, 'RPM')
            zlabel(app.UIAxes2, 'Amplitude [g]')
            app.UIAxes2.XLim = [0 10];
            app.UIAxes2.YLim = [30 210];
            app.UIAxes2.XDir = 'reverse';
            app.UIAxes2.YDir = 'reverse';
            app.UIAxes2.XTick = [0 1 2 3 4 5 6 7 8 9 10];
            app.UIAxes2.XTickLabel = {'0'; '1'; '2'; '3'; '4'; '5'; '6'; '7'; '8'; '9'; '10'};
            app.UIAxes2.YTick = [30 50 70 90 110 130 150 170 190 210];
            app.UIAxes2.YTickLabel = {'30'; '50'; '70'; '90'; '110'; '130'; '150'; '170'; '190'; '210'};
            app.UIAxes = uiaxes(app.GridLayout);
            title(app.UIAxes, 'NSET Memory Matrix Space')
            xlabel(app.UIAxes, 'Wind Speed [MPH]')
            ylabel(app.UIAxes, 'Rotor Speed [RPM]')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [0 30];
            app.UIAxes.YLim = [0 300];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GenerateTextFiles

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end